classdef index < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        imageprocessingUIFigure  matlab.ui.Figure
        Panel_2                  matlab.ui.container.Panel
        saveButton               matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        resizeButton             matlab.ui.control.Button
        ImageprocessingLabel     matlab.ui.control.Label
        ResizeLabel              matlab.ui.control.Label
        yResize                  matlab.ui.control.NumericEditField
        WidthLabel_2             matlab.ui.control.Label
        xResize                  matlab.ui.control.NumericEditField
        WidthLabel               matlab.ui.control.Label
        Switch_2                 matlab.ui.control.Switch
        Switch_2Label            matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        SwitchLabel              matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        SliderLabel              matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        UploadImage              matlab.ui.control.Button
        AddressimageEditField    matlab.ui.control.EditField
        addressimageLabel        matlab.ui.control.Label
        UploadimageLabel         matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        OutputimageTab           matlab.ui.container.Tab
        HeightOutValue           matlab.ui.control.Label
        HeightOutLabel           matlab.ui.control.Label
        WidthOutValue            matlab.ui.control.Label
        WidthOutLabel            matlab.ui.control.Label
        Outputimage              matlab.ui.control.Image
        OriginalimageTab         matlab.ui.container.Tab
        HeightOriginalValue      matlab.ui.control.Label
        HeightOriginalLabel      matlab.ui.control.Label
        WidthOriginalValue       matlab.ui.control.Label
        WidthOriginalLabel       matlab.ui.control.Label
        Originalimage            matlab.ui.control.Image
    end

    
    properties (Access = public)
        SelectedImage
        SelectedImageOriginal
    end
    
    methods (Access = public)
        
        function enable_all(app)
            app.xResize.Enable ="on";
            app.xResize.Editable ="on";
            app.yResize.Enable ="on";
            app.yResize.Editable ="on";
            app.resizeButton.Enable = "on" ;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: UploadImage
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Uplaod image');
            if isequal(file, 0)
                return;
            end
            app.AddressimageEditField.Value = path ;
            image = imread(fullfile(path,file));
            [imageHeight, imageWidth, numChannels] = size(image);
            app.SelectedImage = image ;
            app.SelectedImageOriginal = image;
            app.WidthOriginalValue.Text = num2str(imageWidth);
            app.HeightOriginalValue.Text = num2str(imageHeight);
            app.WidthOutValue.Text = num2str(imageWidth);
            app.HeightOutValue.Text = num2str(imageHeight);
            app.Originalimage.ImageSource = image ;
            app.Outputimage.ImageSource = image ;
            enable_all(app)
        end

        % Button pushed function: resizeButton
        function resizeButtonPushed(app, event)
            x =  app.xResize.Value ;
            y =  app.yResize;
            if x <= 10 || y <=10
                errordlg('X and Y must be greater than 10', 'Error','non-modal');
                return;
            end
            resized_img = imresize(app.SelectedImage, 0.1);
            app.SelectedImage = resized_img;
            [imageHeight, imageWidth, numChannels] = size(resized_img);
            app.WidthOutValue.Text = num2str(imageWidth);
            app.HeightOutValue.Text = num2str(imageHeight);
            app.Outputimage.ImageSource = resized_img;
            app.resetButton.Enable = "on";
            app.saveButton.Enable = "on";
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            choice = uiconfirm(app.imageprocessingUIFigure, 'Do you want to reset image?','Confirmation','Options', {'Yes', 'Cancel'},'DefaultOption', 2);
            if strcmp(choice,'Yes')
                image_originial = app.SelectedImageOriginal;
                app.SelectedImage = image_originial;
                app.Outputimage.ImageSource = image_originial;
                [imageHeight, imageWidth, numChannels] = size(image_originial);
                app.WidthOutValue.Text = num2str(imageWidth);
                app.HeightOutValue.Text = num2str(imageHeight);
                app.resetButton.Enable = "off";
                app.saveButton.Enable = "off";
            else
                return;
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            imsave
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.imageprocessingUIFigure = uifigure('Visible', 'off');
            app.imageprocessingUIFigure.Name = 'image processing';
            app.TabGroup = uitabgroup(app.imageprocessingUIFigure);
            app.OutputimageTab = uitab(app.TabGroup);
            app.OutputimageTab.Title = 'Output image';
            app.Outputimage = uiimage(app.OutputimageTab);
            app.WidthOutLabel = uilabel(app.OutputimageTab);
            app.WidthOutLabel.Text = 'Width : ';
            app.WidthOutValue = uilabel(app.OutputimageTab);
            app.WidthOutValue.Text = '0';
            app.HeightOutLabel = uilabel(app.OutputimageTab);
            app.HeightOutLabel.Text = 'Height : ';
            app.HeightOutValue = uilabel(app.OutputimageTab);
            app.HeightOutValue.Text = '0';
            app.OriginalimageTab = uitab(app.TabGroup);
            app.OriginalimageTab.Title = 'Original image';
            app.Originalimage = uiimage(app.OriginalimageTab);
            app.WidthOriginalLabel = uilabel(app.OriginalimageTab);
            app.WidthOriginalLabel.Text = 'Width : ';
            app.WidthOriginalValue = uilabel(app.OriginalimageTab);
            app.WidthOriginalValue.Text = '0';
            app.HeightOriginalLabel = uilabel(app.OriginalimageTab);
            app.HeightOriginalLabel.Text = 'Height : ';
            app.HeightOriginalValue = uilabel(app.OriginalimageTab);
            app.HeightOriginalValue.Text = '0';
            app.Panel = uipanel(app.imageprocessingUIFigure);
            app.UploadimageLabel = uilabel(app.Panel);
            app.UploadimageLabel.Text = 'Upload image';
            app.addressimageLabel = uilabel(app.Panel);
            app.addressimageLabel.Text = 'Address image :';
            app.AddressimageEditField = uieditfield(app.Panel, 'text');
            app.UploadImage = uibutton(app.Panel, 'push');
            app.UploadImage.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImage.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.UploadImage.Text = '';
            app.Panel_2 = uipanel(app.imageprocessingUIFigure);
            app.SliderLabel = uilabel(app.Panel_2);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel_2);
            app.SwitchLabel = uilabel(app.Panel_2);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'off', 'on'};
            app.Switch.Value = 'off';
            app.Switch_2Label = uilabel(app.Panel_2);
            app.Switch_2Label.Text = 'Switch';
            app.Switch_2 = uiswitch(app.Panel_2, 'slider');
            app.Switch_2.Items = {'off', 'on'};
            app.Switch_2.Value = 'off';
            app.WidthLabel = uilabel(app.Panel_2);
            app.WidthLabel.Enable = 'off';
            app.WidthLabel.Text = 'Width :';
            app.xResize = uieditfield(app.Panel_2, 'numeric');
            app.xResize.ValueDisplayFormat = '%.0f';
            app.xResize.Editable = 'off';
            app.xResize.Enable = 'off';
            app.WidthLabel_2 = uilabel(app.Panel_2);
            app.WidthLabel_2.Enable = 'off';
            app.WidthLabel_2.Text = 'Height :';
            app.yResize = uieditfield(app.Panel_2, 'numeric');
            app.yResize.ValueDisplayFormat = '%.0f';
            app.yResize.Editable = 'off';
            app.yResize.Enable = 'off';
            app.ResizeLabel = uilabel(app.Panel_2);
            app.ResizeLabel.Text = 'Resize';
            app.ImageprocessingLabel = uilabel(app.Panel_2);
            app.ImageprocessingLabel.Text = 'Image processing';
            app.resizeButton = uibutton(app.Panel_2, 'push');
            app.resizeButton.ButtonPushedFcn = createCallbackFcn(app, @resizeButtonPushed, true);
            app.resizeButton.Enable = 'off';
            app.resizeButton.Text = 'resize';
            app.resetButton = uibutton(app.Panel_2, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Icon = 'slrtRebootIcon.png';
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.saveButton = uibutton(app.Panel_2, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = 'saveResults_24.png';
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.imageprocessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = index

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.imageprocessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.imageprocessingUIFigure)
        end
    end
end